classdef appEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        ResetButton                 matlab.ui.control.Button
        TextArea_4                  matlab.ui.control.TextArea
        Label_2                     matlab.ui.control.Label
        LetterGradePanel            matlab.ui.container.Panel
        TextArea_3                  matlab.ui.control.TextArea
        TextArea_3Label             matlab.ui.control.Label
        OverallGradePanel           matlab.ui.container.Panel
        TextArea_2                  matlab.ui.control.TextArea
        TextArea_2Label             matlab.ui.control.Label
        GradePanel                  matlab.ui.container.Panel
        EditField                   matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        SubmitButton                matlab.ui.control.Button
        GradeCalculatorofDoomLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Predefine all important variables
        GradeAvg=0; %This variable will be the grade average given all the
        %previous inputs
        N=0; %This variable represents how many grades are put in
        grades=[]; %Predefine this as an empty array and is where each
        %grade submitted will be stored for plotting
        GradeAvgs=[]; %Predefine this variable as an empty array and is
        %where the grade average after the most recently submitted grade is
        %stored for plotting
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %This function takes in each grade number submitted (when
            %clicking the submit button) and stores each grade into an
            %array where the functions displays the overall grade average
            %and letter grade while plotting the grade average over time.
            grade=app.EditField.Value; %Grade typed in
            app.grades(end+1)=grade;%Add grade value to grades array
            app.N=app.N+1; %Add to grade number
            app.GradeAvg=mean(app.grades);%Calculate average of grades submitted
            app.GradeAvgs(end+1)=app.GradeAvg; %Store the grade averages for plotting
            app.TextArea_2.Value=num2str(app.GradeAvg); %Display grade average
            % If statement for displaying given letter grade based on grade average
            % (10 point scale)
            if app.GradeAvg>=90
                app.TextArea_3.Value='A';
            elseif app.GradeAvg>=80
                app.TextArea_3.Value='B';
            elseif app.GradeAvg>=70
                app.TextArea_3.Value='C';
            else
                app.TextArea_3.Value='F';
            end
            % If statement plots grade averages as a function of number of
            % grades submitted. Plots in red for under 70 grade average,
            % yellow for 70-79, and green for >80
            if app.GradeAvg<70
                plot(app.UIAxes,0:app.N,[0,app.GradeAvgs],'r-',"LineWidth",5);
            elseif app.GradeAvg<80
                plot(app.UIAxes,0:app.N,[0,app.GradeAvgs],'y-',"LineWidth",5);
            else
                plot(app.UIAxes,0:app.N,[0,app.GradeAvgs],'g-',"LineWidth",5);
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Value changing function: TextArea_2
        function TextArea_2ValueChanging(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % This function resets all the relevant variables when the
            % reset button is pushed
            app.N = 0; %Number of grades to 0
            app.GradeAvg = 0; %Grade Average to 0
            %Empty arrays
            app.grades = [];
            app.GradeAvgs=[];
            %Reset text fields
            app.TextArea_2.Value = '#';
            app.TextArea_3.Value = '#';
            app.EditField.Value=0;
            %Clear axis
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Number of Grades')
            ylabel(app.UIAxes, 'Grade Average')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [0 1 2 3 4 5];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.GradeCalculatorofDoomLabel = uilabel(app.UIFigure);
            app.GradeCalculatorofDoomLabel.Text = 'Grade Calculator of Doom';
            app.Panel = uipanel(app.UIFigure);
            app.SubmitButton = uibutton(app.Panel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.GradePanel = uipanel(app.Panel);
            app.GradePanel.Title = 'Grade';
            app.Label_3 = uilabel(app.GradePanel);
            app.Label_3.Text = '';
            app.EditField = uieditfield(app.GradePanel, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.OverallGradePanel = uipanel(app.Panel);
            app.OverallGradePanel.Title = 'Overall Grade';
            app.TextArea_2Label = uilabel(app.OverallGradePanel);
            app.TextArea_2Label.Text = '';
            app.TextArea_2 = uitextarea(app.OverallGradePanel);
            app.TextArea_2.ValueChangingFcn = createCallbackFcn(app, @TextArea_2ValueChanging, true);
            app.TextArea_2.Value = {'#'};
            app.LetterGradePanel = uipanel(app.Panel);
            app.LetterGradePanel.Title = 'Letter Grade';
            app.TextArea_3Label = uilabel(app.LetterGradePanel);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.LetterGradePanel);
            app.TextArea_3.Value = {'#'};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.TextArea_4 = uitextarea(app.Panel);
            app.TextArea_4.Value = {'='};
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appEC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end